classdef appEDO_PVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrabalhoFigure         matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        DadosPVI               matlab.ui.container.Panel
        ftyLabel               matlab.ui.control.Label
        eF                     matlab.ui.control.EditField
        aLabel                 matlab.ui.control.Label
        eA                     matlab.ui.control.NumericEditField
        eB                     matlab.ui.control.NumericEditField
        bLabel                 matlab.ui.control.Label
        eN                     matlab.ui.control.NumericEditField
        nLabel                 matlab.ui.control.Label
        y0Label                matlab.ui.control.Label
        eY0                    matlab.ui.control.NumericEditField
        SdeM                   matlab.ui.container.ButtonGroup
        MEulerbtn              matlab.ui.control.RadioButton
        MEulerMbtn             matlab.ui.control.RadioButton
        RungeKuttadeordem2btn  matlab.ui.control.RadioButton
        RungeKuttadeordem4btn  matlab.ui.control.RadioButton
        ODE45btn               matlab.ui.control.RadioButton
        DormandPrincebtn       matlab.ui.control.RadioButton
        Todosbtn               matlab.ui.control.RadioButton
        SairButton             matlab.ui.control.Button
        LimparButton           matlab.ui.control.Button
        MudardemtodoparaatualizaraaplicaoLabel  matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        UITable                matlab.ui.control.Table
        UIAxes                 matlab.ui.control.UIAxes
        InfoMenu               matlab.ui.container.Menu
        RealizadoporMenu       matlab.ui.container.Menu
        AutoresMenu            matlab.ui.container.Menu
        RelatrioMenu           matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            SdeMSelectionChanged(app, []) % atualizar a app automáticamente consoante a opção que o utilizador escolhe
        end

        % Selection changed function: SdeM
        function SdeMSelectionChanged(app, event)
          escolha = app.SdeM.SelectedObject;                    
                                                                    
        switch escolha 
            
                case app.MEulerbtn
                     syms y(t) 
                    
                        strf = app.eF.Value;                   
                        f  = @(t,y) eval(vectorize(strf));                    
                        a  = app.eA.Value;                   
                        b  = app.eB.Value;                   
                        n  = app.eN.Value;                    
                        y0 = app.eY0.Value;
                       
                     TestF = MException("MATLAB:SdeM:badF", ...
                                         "Introduza uma função em t e y");
                     
                     TestAMaior=MException("MATLAB:SdeM:badMaior", ...
                                          "O valor do b está incorreto");
                     
                     TestN = MException("MATLAB:MyAtualizar:badN", ...
                                        "O valor do n está incorreto");

    
                 try 
                        try 
                            testfunc = f(('t'),('y'));
                            set(app.eF,'BackgroundColor','g');
                            
                        catch
                            set(app.eF,'BackgroundColor','r');
                            throw(TestF)
                            
                        end
                        
                        if ~(isscalar(b) && isreal(b) && b > a)
                            set(app.eB,'BackgroundColor','r');
                            throw(TestAMaior)
                        else
                            set(app.eB,'BackgroundColor','g');
                        end
                        
                         if ~(isscalar(n) && n == floor(n) && n > 0)
                             set(app.eN,'BackgroundColor','r');
                             throw(TestN)
                         else
                                set(app.eN,'BackgroundColor','g');
                         end
                                                
                     sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                     g =@(t) eval(vectorize(char(sExata)));
                     h = (b-a)/n;
                     t = a:h:b;
                     
                     yExata = g(t); 
                    
                     yEuler = MEuler(f,a,b,n,y0);
                     
                     erroEuler = abs(yExata-yEuler);
                     
                     fplot(app.UIAxes,[a,b]);
                     plot(app.UIAxes,t,yExata,"-yo","LineWidth",1.5)
                     hold(app.UIAxes,"on");
                     plot(app.UIAxes,t,yEuler,"-ro","LineWidth",1.5)
                     hold(app.UIAxes,"off");
                                                              
                     legend(app.UIAxes,'Solução Exata','Euler')  
                     
                     tabela = [t.',yExata.',yEuler.',erroEuler.'];
                     
                     T =array2table(tabela,"VariableNames",{'t','Exata','Euler','Erro Euler'});
                     
                     app.UITable.Data = T;
                     
                     app.UITable.ColumnName = {'t','Exata','Euler','Erro Euler'};
                catch Me
                     errordlg(['Ocorreu um Erro: ' Me.message],'Erro','modal');
                end     
                    
                case app.MEulerMbtn
                      syms y(t) 
                    
                        strf = app.eF.Value;                   
                        f  = @(t,y) eval(vectorize(strf));                    
                        a  = app.eA.Value;                   
                        b  = app.eB.Value;                   
                        n  = app.eN.Value;                    
                        y0 = app.eY0.Value;
                        
                     TestF = MException("MATLAB:SdeM:badF", ...
                                         "Introduza uma função em t e y");
                     
                     TestAMaior=MException("MATLAB:SdeM:badMaior", ...
                                          "O valor do b tem que ser maior que a");
                     
                     TestN = MException("MATLAB:MyAtualizar:badN", ...
                                        "O valor do n está incorreto");
                        
               try 
                        try 
                            testfunc = f(('t'),('y'));
                            set(app.eF,'BackgroundColor','g');
                            
                        catch
                            set(app.eF,'BackgroundColor','r');
                            throw(TestF)
                            
                        end
                        
                        if ~(isscalar(b) && isreal(b) && b > a)
                            set(app.eB,'BackgroundColor','r');
                            throw(TestAMaior)
                        else
                            set(app.eB,'BackgroundColor','g');
                        end
                        
                         if ~(isscalar(n) && n == floor(n) && n > 0)
                             set(app.eN,'BackgroundColor','r');
                             throw(TestN)
                         else
                                set(app.eN,'BackgroundColor','g');
                         end
                        
                    sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                    g =@(t) eval(vectorize(char(sExata)));
                    h = (b-a)/n;
                    t = a:h:b;
                    
                    
                     yExata = g(t);
                     
                     yEulerMelh = MEulerMelh(f,a,b,n,y0);
                     
                     erroEulerMelh = abs(yExata-yEulerMelh);
                     
                     plot(app.UIAxes,t,yExata,"-yo","LineWidth",1.5)
                     hold(app.UIAxes,"on");
                     plot(app.UIAxes,t,yEulerMelh,"-bo","LineWidth",1.5)
                     hold(app.UIAxes,"off");
                     
                     
                     legend(app.UIAxes,'Solução Exata','Euler Melhorado')  
                     
                     tabela = [t.',yExata.',yEulerMelh.',erroEulerMelh.'];
                     
                     T =array2table(tabela,"VariableNames",{'t','Exata','Euler','Erro Euler Melhorado'});
                     
                     app.UITable.Data = T;
                     
                     app.UITable.ColumnName = {'t','Exata','Euler Melhorado','Erro Euler Melhorado'};
                     
                catch Me
                     errordlg(['Ocorreu um Erro: ' Me.message],'Erro','modal');
                end
                     
                case app.RungeKuttadeordem2btn
                     syms y(t) 
                    
                        strf = app.eF.Value;                   
                        f  = @(t,y) eval(vectorize(strf));                    
                        a  = app.eA.Value;                   
                        b  = app.eB.Value;                   
                        n  = app.eN.Value;                    
                        y0 = app.eY0.Value;
                        
                      TestF = MException("MATLAB:SdeM:badF", ...
                                         "Introduza uma função em t e y");
                     
                      TestAMaior=MException("MATLAB:SdeM:badMaior", ...
                                          "O valor do b tem que ser maior que a");
                     
                      TestN = MException("MATLAB:MyAtualizar:badN", ...
                                        "O valor do n está incorreto");
                try  
                        try 
                            testfunc = f(('t'),('y'));
                            set(app.eF,'BackgroundColor','g');
                            
                        catch
                            set(app.eF,'BackgroundColor','r');
                            throw(TestF)
                            
                        end
                        
                        if ~(isscalar(b) && isreal(b) && b > a)
                            set(app.eB,'BackgroundColor','r');
                            throw(TestAMaior)
                        else
                            set(app.eB,'BackgroundColor','g');
                        end
                        
                         if ~(isscalar(n) && n == floor(n) && n > 0)
                             set(app.eN,'BackgroundColor','r');
                             throw(TestN)
                         else
                                set(app.eN,'BackgroundColor','g');
                         end
                        
                     sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                     g =@(t) eval(vectorize(char(sExata)));
                     h = (b-a)/n;
                     t = a:h:b;
                     
                     yExata = g(t);
                    
                     yRK2 = NRK2(f,a,b,n,y0);
                    
                     erroRK2 = abs(yExata-yRK2);
                     
                     plot(app.UIAxes,t,yExata,"-yo","LineWidth",1.5)
                     hold(app.UIAxes,"on");
                     plot(app.UIAxes,t,yRK2,"-go","LineWidth",1.5)
                     hold(app.UIAxes,"off");
                     
                     legend(app.UIAxes,'Solução Exata','Runge-Kutta de ordem 2')  
                     
                     tabela = [t.',yExata.',yRK2.',erroRK2.'];
                     
                     T =array2table(tabela,"VariableNames",{'t','Exata','RK2','Erro RK2'});
                     
                     app.UITable.Data = T;
                     
                     app.UITable.ColumnName = {'t','Exata','RK2','Erro RK2'};
                catch Me
                     errordlg(['Ocorreu um Erro: ' Me.message],'Erro','modal');
                end
                                     
                case app.RungeKuttadeordem4btn
                     syms y(t) 
                    
                        strf = app.eF.Value;                   
                        f  = @(t,y) eval(vectorize(strf));                    
                        a  = app.eA.Value;                   
                        b  = app.eB.Value;                   
                        n  = app.eN.Value;                    
                        y0 = app.eY0.Value;
                        
                        TestF = MException("MATLAB:SdeM:badF", ...
                                         "Introduza uma função em t e y");
                     
                       TestAMaior=MException("MATLAB:SdeM:badMaior", ...
                                          "O valor do b tem que ser maior que a");
                     
                       TestN = MException("MATLAB:MyAtualizar:badN", ...
                                        "O valor do n está incorreto");
                 try  
                        try 
                            testfunc = f(('t'),('y'));
                            set(app.eF,'BackgroundColor','g');
                            
                        catch
                            set(app.eF,'BackgroundColor','r');
                            throw(TestF)
                            
                        end
                        
                        if ~(isscalar(b) && isreal(b) && b > a)
                            set(app.eB,'BackgroundColor','r');
                            throw(TestAMaior)
                        else
                            set(app.eB,'BackgroundColor','g');
                        end
                        
                         if ~(isscalar(n) && n == floor(n) && n > 0)
                             set(app.eN,'BackgroundColor','r');
                             throw(TestN)
                         else
                                set(app.eN,'BackgroundColor','g');
                         end
                        
                     sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                     g =@(t) eval(vectorize(char(sExata)));
                     h = (b-a)/n;
                     t = a:h:b;
                     
                     yExata = g(t);
                    
                     yRK4 = NRK4(f,a,b,n,y0);
                    
                     erroRK4 = abs(yExata-yRK4);
                     
                     plot(app.UIAxes,t,yExata,"-yo","LineWidth",1.5)
                     hold(app.UIAxes,"on");
                     plot(app.UIAxes,t,yRK4,"-co","LineWidth",1.5)
                     hold(app.UIAxes,"off");
                     
                     legend(app.UIAxes,'Solução Exata','Runge-Kutta de ordem 4')  
                     
                     tabela = [t.',yExata.',yRK4.',erroRK4.'];
                     
                     T =array2table(tabela,"VariableNames",{'t','Exata','RK4','Erro RK4'});
                     
                     app.UITable.Data = T;
                     
                     app.UITable.ColumnName = {'t','Exata','RK4','Erro RK4'};
                catch Me
                     errordlg(['Ocorreu um Erro: ' Me.message],'Erro','modal');
                end
                     
                 case app.ODE45btn
                      syms y(t) 
                    
                        strf = app.eF.Value;                   
                        f  = @(t,y) eval(vectorize(strf));                    
                        a  = app.eA.Value;                   
                        b  = app.eB.Value;                   
                        n  = app.eN.Value;                    
                        y0 = app.eY0.Value;
                        
                       TestF = MException("MATLAB:SdeM:badF", ...
                                         "Introduza uma função em t e y");
                     
                       TestAMaior=MException("MATLAB:SdeM:badMaior", ...
                                          "O valor do b tem que ser maior que a");
                     
                       TestN = MException("MATLAB:MyAtualizar:badN", ...
                                        "O valor do n está incorreto");
                 try  
                        try 
                            testfunc = f(('t'),('y'));
                            set(app.eF,'BackgroundColor','g');
                            
                        catch
                            set(app.eF,'BackgroundColor','r');
                            throw(TestF)
                            
                        end
                        
                        if ~(isscalar(b) && isreal(b) && b > a)
                            set(app.eB,'BackgroundColor','r');
                            throw(TestAMaior)
                        else
                            set(app.eB,'BackgroundColor','g');
                        end
                        
                         if ~(isscalar(n) && n == floor(n) && n > 0)
                             set(app.eN,'BackgroundColor','r');
                             throw(TestN)
                         else
                                set(app.eN,'BackgroundColor','g');
                         end
                        
                      sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                      g =@(t) eval(vectorize(char(sExata)));
                      h = (b-a)/n;
                      t = a:h:b;
                    
                      yExata = g(t);
                      
                      yODE45 = NODE45(f,a,b,n,y0);
                    
                      erroODE45 = abs(yExata-yODE45);
                      plot(app.UIAxes,t,yExata,"-yo","LineWidth",1.5)
                      hold(app.UIAxes,"on");
                      plot(app.UIAxes,t,yODE45,"-ko","LineWidth",1.5)
                      hold(app.UIAxes,"off");
                     
                      legend(app.UIAxes,'Solução Exata','ODE45')  
                     
                      tabela = [t.',yExata.',yODE45.',erroODE45.'];
                     
                      T =array2table(tabela,"VariableNames",{'t','Exata','ODE45','Erro ODE45'});
                     
                      app.UITable.Data = T;
                     
                      app.UITable.ColumnName = {'t','Exata','ODE45','Erro ODE45'};
                catch Me
                     errordlg(['Ocorreu um Erro: ' Me.message],'Erro','modal');
                end
                      
                 case app.DormandPrincebtn
                      syms y(t) 
                    
                        strf = app.eF.Value;                   
                        f  = @(t,y) eval(vectorize(strf));                    
                        a  = app.eA.Value;                   
                        b  = app.eB.Value;                   
                        n  = app.eN.Value;                    
                        y0 = app.eY0.Value;
                        
                        TestF = MException("MATLAB:SdeM:badF", ...
                                         "Introduza uma função em t e y");
                     
                       TestAMaior=MException("MATLAB:SdeM:badMaior", ...
                                          "O valor do b tem que ser maior que a");
                     
                       TestN = MException("MATLAB:MyAtualizar:badN", ...
                                        "O valor do n está incorreto");
                 try  
                        try 
                            testfunc = f(('t'),('y'));
                            set(app.eF,'BackgroundColor','g');
                            
                        catch
                            set(app.eF,'BackgroundColor','r');
                            throw(TestF)
                            
                        end
                        
                        if ~(isscalar(b) && isreal(b) && b > a)
                            set(app.eB,'BackgroundColor','r');
                            throw(TestAMaior)
                        else
                            set(app.eB,'BackgroundColor','g');
                        end
                        
                         if ~(isscalar(n) && n == floor(n) && n > 0)
                             set(app.eN,'BackgroundColor','r');
                             throw(TestN)
                         else
                                set(app.eN,'BackgroundColor','g');
                         end

                        
                      sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                      g =@(t) eval(vectorize(char(sExata)));
                      h = (b-a)/n;
                      t = a:h:b;
                    
                      yExata = g(t);
                     
                      yDormandPrince = DormandPrince(f,a,b,n,y0);
                                         
                      erroDormandPrince = abs(yExata-yDormandPrince);
                      
                      plot(app.UIAxes,t,yExata,"-yo","LineWidth",1.5)
                      hold(app.UIAxes,"on");
                      plot(app.UIAxes,t,yDormandPrince,"-mo","LineWidth",1.5)
                      hold(app.UIAxes,"off");
                     
                      legend(app.UIAxes,'Solução Exata','Dormand Prince')  
                     
                      tabela = [t.',yExata.',yDormandPrince.',erroDormandPrince.'];
                     
                      T =array2table(tabela,"VariableNames",{'t','Exata','Dormand Prince','Erro Dormand Prince'});
                     
                      app.UITable.Data = T;
                     
                      app.UITable.ColumnName = {'t','Exata','Dormand Prince','Erro Dormand Prince'};
                catch Me
                     errordlg(['Ocorreu um Erro: ' Me.message],'Erro','modal');
                end
                      
                 case app.Todosbtn
                      syms y(t) 
                    
                        strf = app.eF.Value;                   
                        f  = @(t,y) eval(vectorize(strf));                    
                        a  = app.eA.Value;                   
                        b  = app.eB.Value;                   
                        n  = app.eN.Value;                    
                        y0 = app.eY0.Value;
                        
                        TestF = MException("MATLAB:SdeM:badF", ...
                                         "Introduza uma função em t e y");
                     
                       TestAMaior=MException("MATLAB:SdeM:badMaior", ...
                                          "O valor do b tem que ser maior que a");
                     
                       TestN = MException("MATLAB:MyAtualizar:badN", ...
                                        "O valor do n está incorreto");
                 try  
                        try 
                            testfunc = f(('t'),('y'));
                            set(app.eF,'BackgroundColor','g');
                            
                        catch
                            set(app.eF,'BackgroundColor','r');
                            throw(TestF)
                            
                        end
                        
                        if ~(isscalar(b) && isreal(b) && b > a)
                            set(app.eB,'BackgroundColor','r');
                            throw(TestAMaior)
                        else
                            set(app.eB,'BackgroundColor','g');
                        end
                        
                         if ~(isscalar(n) && n == floor(n) && n > 0)
                             set(app.eN,'BackgroundColor','r');
                             throw(TestN)
                         else
                                set(app.eN,'BackgroundColor','g');
                         end
                        
                      sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                      g =@(t) eval(vectorize(char(sExata)));
                      h = (b-a)/n;
                      t = a:h:b;
                    
                      yExata = g(t);
                      
                      yEuler = MEuler(f,a,b,n,y0);
                      yEulerMelh = MEulerMelh(f,a,b,n,y0);
                      yRK2 = NRK2(f,a,b,n,y0);
                      yRK4 = NRK4(f,a,b,n,y0);
                      yODE45 = NODE45(f,a,b,n,y0);
                      yDormandPrince = DormandPrince(f,a,b,n,y0);
                      
                      erroEuler = abs(yExata-yEuler);
                      erroEulerMelh = abs(yExata-yEulerMelh);
                      erroRK2 = abs(yExata-yRK2);
                      erroRK4 = abs(yExata-yRK4);
                      erroODE45 = abs(yExata-yODE45);
                      erroDormandPrince = abs(yExata-yDormandPrince);
                      
                      plot(app.UIAxes,t,yExata,"-yo","LineWidth",1.5)
                      
                      hold(app.UIAxes,"on");
                      
                      plot(app.UIAxes,t,yEuler,"-ro","LineWidth",1.5)
                      
                      plot(app.UIAxes,t,yEulerMelh,"-bo","LineWidth",1.5)
                      
                      plot(app.UIAxes,t,yRK2,"-go","LineWidth",1.5)
                      
                      plot(app.UIAxes,t,yRK4,"-co","LineWidth",1.5)
                      
                      plot(app.UIAxes,t,yODE45,"-ko","LineWidth",1.5)
                    
                      plot(app.UIAxes,t,yDormandPrince,"-mo","LineWidth",1.5)
                      
                      hold(app.UIAxes,"off");
                     
                      legend(app.UIAxes,'Solução Exata','Euler','Euler Melhorado','Runge-Kutta de ordem 2','Runge-Kutta de ordem 4','ODE45','Dormand Prince')  
                     
                      tabela = [t.',yExata.',yEuler.',yEulerMelh.',yRK2.',yRK4.',yODE45.',yDormandPrince.',erroEuler.',erroEulerMelh.',erroRK2.',erroRK4.',erroODE45.',erroDormandPrince.'];
                     
                      T =array2table(tabela,"VariableNames",{'t','Exata','Euler','Euler Melhorado','RK2','RK4','ODE45','Dormand Prince','Erro Euler','Erro Euler Melhorado','Erro RK2','Erro RK4','Erro ODE45','Erro Dormand Prince'});
                     
                      app.UITable.Data = T;
                     
                      app.UITable.ColumnName = {'t','Exata','Euler','Euler Melhorado','RK2','RK4','ODE45','Dormand Prince','Erro Euler','Erro Euler Melhorado','Erro RK2','Erro RK4','Erro ODE45','Erro Dormand Prince'};
                catch Me
                     errordlg(['Ocorreu um Erro: ' Me.message],'Erro','modal');
                end
                
        end
                 
        end

        % Button pushed function: SairButton
        function SairButtonPushed(app, event)
            exitmessage = questdlg("Deseja mesmo sair?","Sair?","Sim","Não","Não");
            
            if strcmp(exitmessage,"Sim")
                close(app.TrabalhoFigure)
            end
        end

        % Button pushed function: LimparButton
        function LimparButtonPushed(app, event)
            cla(app.UIAxes);                      % limpa o gráfico
            app.UITable.Data = {};                %
            set(app.UITable,'columnname',{});     % limpa a tabela
            app.eF.Value = '';                    % 
            app.eA.Value = 0;                     %
            app.eB.Value = 0;                     %
            app.eN.Value = 1;                     %
            app.eY0.Value = 0;                    % limpa as variáveis
        end

        % Menu selected function: RealizadoporMenu
        function RealizadoporMenuSelected(app, event)
             msgbox(["Paulo Gouveia a2020121705.isec.pt";"Miguel Ferreira a2020107016.isec.pt","Pablo Amaral a2020143935.isec.pt"],"Autores:");                       
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            run("Autores.mlapp") %abrir outra aplicação através do menu
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            open Relatório_Atividade1.pdf
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TrabalhoFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {527, 527};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {301, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrabalhoFigure = uifigure('Visible', 'off');
            app.TrabalhoFigure.Name = 'EDO_PVI';
            app.TrabalhoFigure.Icon = 'LogoIsec.png';
            app.TrabalhoFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TrabalhoFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosPVI = uipanel(app.LeftPanel);
            app.DadosPVI.Title = 'Dados do PVI';
            app.ftyLabel = uilabel(app.DadosPVI);
            app.ftyLabel.Text = 'f(t,y) = ';
            app.eF = uieditfield(app.DadosPVI, 'text');
            app.eF.Value = '-2*t*y';
            app.aLabel = uilabel(app.DadosPVI);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.DadosPVI, 'numeric');
            app.eB = uieditfield(app.DadosPVI, 'numeric');
            app.eB.Value = 1.5;
            app.bLabel = uilabel(app.DadosPVI);
            app.bLabel.Text = 'b';
            app.eN = uieditfield(app.DadosPVI, 'numeric');
            app.eN.RoundFractionalValues = 'on';
            app.eN.Value = 10;
            app.nLabel = uilabel(app.DadosPVI);
            app.nLabel.Text = 'n';
            app.y0Label = uilabel(app.DadosPVI);
            app.y0Label.Text = 'y0';
            app.eY0 = uieditfield(app.DadosPVI, 'numeric');
            app.eY0.Value = 2;
            app.SdeM = uibuttongroup(app.LeftPanel);
            app.SdeM.SelectionChangedFcn = createCallbackFcn(app, @SdeMSelectionChanged, true);
            app.SdeM.Title = 'Seleção de Métodos';
            app.MEulerbtn = uiradiobutton(app.SdeM);
            app.MEulerbtn.Text = ' Método Euler';
            app.MEulerbtn.Value = true;
            app.MEulerMbtn = uiradiobutton(app.SdeM);
            app.MEulerMbtn.Text = ' Método Euler Melhorado';
            app.RungeKuttadeordem2btn = uiradiobutton(app.SdeM);
            app.RungeKuttadeordem2btn.Text = ' Runge-Kutta de ordem 2';
            app.RungeKuttadeordem4btn = uiradiobutton(app.SdeM);
            app.RungeKuttadeordem4btn.Text = ' Runge-Kutta de ordem 4';
            app.ODE45btn = uiradiobutton(app.SdeM);
            app.ODE45btn.Text = ' ODE45';
            app.DormandPrincebtn = uiradiobutton(app.SdeM);
            app.DormandPrincebtn.Text = ' Dormand Prince';
            app.Todosbtn = uiradiobutton(app.SdeM);
            app.Todosbtn.Text = ' Todos';
            app.SairButton = uibutton(app.LeftPanel, 'push');
            app.SairButton.ButtonPushedFcn = createCallbackFcn(app, @SairButtonPushed, true);
            app.SairButton.Text = 'Sair';
            app.LimparButton = uibutton(app.LeftPanel, 'push');
            app.LimparButton.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.LimparButton.Text = 'Limpar';
            app.MudardemtodoparaatualizaraaplicaoLabel = uilabel(app.LeftPanel);
            app.MudardemtodoparaatualizaraaplicaoLabel.Text = 'Mudar de método para atualizar a aplicação:';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Gráficos')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.InfoMenu = uimenu(app.TrabalhoFigure);
            app.InfoMenu.Text = 'Info';
            app.RealizadoporMenu = uimenu(app.InfoMenu);
            app.RealizadoporMenu.MenuSelectedFcn = createCallbackFcn(app, @RealizadoporMenuSelected, true);
            app.RealizadoporMenu.Text = 'Realizado por:';
            app.AutoresMenu = uimenu(app.InfoMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.RelatrioMenu = uimenu(app.InfoMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            % Show the figure after all components are created
            app.TrabalhoFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appEDO_PVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrabalhoFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrabalhoFigure)
        end
    end
end