classdef appPVI_SED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrabalhoFigure                  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        DadosPVI                        matlab.ui.container.Panel
        aLabel                          matlab.ui.control.Label
        eA                              matlab.ui.control.NumericEditField
        eB                              matlab.ui.control.NumericEditField
        bLabel                          matlab.ui.control.Label
        eN                              matlab.ui.control.NumericEditField
        nLabel                          matlab.ui.control.Label
        u0Label                         matlab.ui.control.Label
        eU0                             matlab.ui.control.NumericEditField
        ftuvLabel                       matlab.ui.control.Label
        eF                              matlab.ui.control.EditField
        gtuvLabel                       matlab.ui.control.Label
        eG                              matlab.ui.control.EditField
        v0Label                         matlab.ui.control.Label
        eV0                             matlab.ui.control.NumericEditField
        SdeM                            matlab.ui.container.ButtonGroup
        MEulerbtn                       matlab.ui.control.RadioButton
        MEulerMbtn                      matlab.ui.control.RadioButton
        RungeKuttadeordem2btn           matlab.ui.control.RadioButton
        RungeKuttadeordem4btn           matlab.ui.control.RadioButton
        Todosbtn                        matlab.ui.control.RadioButton
        SairButton                      matlab.ui.control.Button
        LimparButton                    matlab.ui.control.Button
        bAtualizar                      matlab.ui.control.Button
        ProblemasdinmicosDropDownLabel  matlab.ui.control.Label
        ddProblemas                     matlab.ui.control.DropDown
        RightPanel                      matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        InfoMenu                        matlab.ui.container.Menu
        RealizadoporMenu                matlab.ui.container.Menu
        AutoresMenu                     matlab.ui.container.Menu
        RelatrioMenu                    matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SairButton
        function SairButtonPushed(app, event)
            exitmessage = questdlg("Deseja mesmo sair?","Sair?","Sim","Não","Não");
            
            if strcmp(exitmessage,"Sim")
                close(app.TrabalhoFigure)
            end
        end

        % Button pushed function: LimparButton
        function LimparButtonPushed(app, event)
            cla(app.UIAxes);                      % limpa o gráfico
            app.UITable.Data = {};                %
            set(app.UITable,'columnname',{});     % limpa a tabela
            app.eF.Value = 'v';                   %
            app.eG.Value = '';                    % 
            app.eA.Value = 0;                     %
            app.eB.Value = 0;                     %
            app.eN.Value = 1;                     %
            app.eU0.Value = 0;                    %
            app.eU0.Value = 0;                    % limpa as variáveis
        end

        % Menu selected function: RealizadoporMenu
        function RealizadoporMenuSelected(app, event)
             msgbox(["Paulo Gouveia a2020121705@isec.pt";"Miguel Ferreira a2020107016@isec.pt";"Pablo Amaral a2020143935@isec.pt"],"Autores:")                      
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            run("Autores.mlapp") %abrir outra aplicação através do menu
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            open AM2_Relatório_Atividade2.pdf
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TrabalhoFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {637, 637};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {298, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
             escolha = app.SdeM.SelectedObject;
             
             valueproblema = app.ddProblemas.Value;
             
            syms u(t) v(t)
            strF = app.eF.Value;
            f =@(t,u,v) eval(vectorize(strF));
            strG = app.eG.Value;
            g =@(t,u,v) eval(vectorize(strG));            
            a = app.eA.Value;
            b = app.eB.Value;   
            n = app.eN.Value;           
            u0 = app.eU0.Value; 
            v0 = app.eV0.Value;  
                   
                    TestF = MException("MATLAB:SdeM:badF", ...
                                       "Introduza uma função em u e v");
                     
                    TestAMaior=MException("MATLAB:SdeM:badMaior", ...
                                           "O valor do b está incorreto");
                     
                    TestN = MException("MATLAB:MyAtualizar:badN", ...
                                        "O valor do n está incorreto");
try
                        try 
                            z = g(t,u,v);
                            set(app.eG,'BackgroundColor','g');
                            
                        catch
                            set(app.eG,'BackgroundColor','r');
                            throw(TestF)
                            
                        end
                        
                        if ~(isscalar(b) && isreal(b) && b > a)
                            set(app.eB,'BackgroundColor','r');
                            throw(TestAMaior)
                        else
                            set(app.eB,'BackgroundColor','g');
                        end
                        
                         if ~(isscalar(n) && n == floor(n) && n > 0)
                             set(app.eN,'BackgroundColor','r');
                             throw(TestN)
                         else
                                set(app.eN,'BackgroundColor','g');
                         end
                          
                if ~(valueproblema == '1')     
                    eqns = [diff(u,t)==f(t,u,v),diff(v,t)==g(t,u,v)];
                    ci = [u(0)==u0,v(0)==v0];                
                    [sExataU,~] =dsolve(eqns,ci);
                    h = @(t) eval(vectorize(char(sExataU)));
                end
                          
                     [t,uEuler,~] = NEulerSED(f,g,a,b,n,u0,v0);
                     [~,uEulerMelh,~] = NEulerMSED(f,g,a,b,n,u0,v0);
                     [~,uRK2,~] = NRK2SED(f,g,a,b,n,u0,v0);
                     [~,uRK4,~] = NRK4SED(f,g,a,b,n,u0,v0);
                     
                     if ~(valueproblema == '1') 
                         uExata = h(t);                         
                         erroEuler = abs(uExata-uEuler);
                         erroEulerMelh = abs(uExata-uEulerMelh);
                         erroRK2 = abs(uExata-uRK2);
                         erroRK4 = abs(uExata-uRK4);
                         plot(app.UIAxes,t,uExata,"-yo")
                         hold(app.UIAxes,"on")                                           
                     end


        switch escolha 
            
                case app.MEulerbtn
                    
                     plot(app.UIAxes,t,uEuler,"-ro","LineWidth",1.5)
                     if ~(valueproblema == '1') 
                         legenda = {'Solução Exata','Euler'};                                                                                                  
                         tabela = [t.',uExata.',uEuler.',erroEuler.'];
                         nomeColunas = {'t','Exata','Euler','erroEuler'};
                     else 
                         legenda = {'Euler'};
                         tabela = [t.',uEuler.'];
                         nomeColunas = {'t','Euler'};
                     end
                case app.MEulerMbtn                                          
                         plot(app.UIAxes,t,uEulerMelh,"-bo","LineWidth",1.5)
                      if ~(valueproblema == '1')
                         legenda = {'Solução Exata','Euler Melhorado'};                      
                         tabela = [t.',uExata.',uEulerMelh.',erroEulerMelh.'];                                         
                         nomeColunas = {'t','Exata','Euler Melhorado','Erro Euler Melhorado'};
                     else
                         legenda = {'Euler Melhorado'};
                         tabela = [t.',uEulerMelh.'];
                         nomeColunas = {'t','Euler Melhorado'};
                     end
                case app.RungeKuttadeordem2btn  
                     
                         plot(app.UIAxes,t,uRK2,"-go","LineWidth",1.5)
                     if ~(valueproblema == '1') 
                         legenda = {'Solução Exata','Runge-Kutta de ordem 2'};                     
                         tabela = [t.',uExata.',uRK2.',erroRK2.'];                    
                         nomeColunas = {'t','Exata','RK2','Erro RK2'};
                     else
                         legenda = {'Runge-Kutta de ordem 2'};                     
                         tabela = [t.',uRK2.'];                    
                         nomeColunas = {'t','RK2'};
                     end
               case app.RungeKuttadeordem4btn  
                         plot(app.UIAxes,t,uRK4,"-co","LineWidth",1.5)
                    if ~(valueproblema == '1')
                         legenda = {'Solução Exata','Runge-Kutta de ordem 4'};                      
                         tabela = [t.',uExata.',uRK4.',erroRK4.'];          
                         nomeColunas = {'t','Exata','RK4','Erro RK4'};
                   else
                         legenda = {'Runge-Kutta de ordem 4'};                     
                         tabela = [t.',uRK4.'];                    
                         nomeColunas = {'t','RK4'};
                   end
               case app.Todosbtn                                        
                          plot(app.UIAxes,t,uEuler,"-ro","LineWidth",1.5)
                           hold(app.UIAxes,"on")
                          plot(app.UIAxes,t,uEulerMelh,"-bo","LineWidth",1.5)
                           hold(app.UIAxes,"on")
                          plot(app.UIAxes,t,uRK2,"-go","LineWidth",1.5)
                           hold(app.UIAxes,"on")
                          plot(app.UIAxes,t,uRK4,"-co","LineWidth",1.5)
                           hold(app.UIAxes,"on")
                       if ~(valueproblema == '1')
                          legenda = {'Solução Exata','Euler','Euler Melhorado','Runge-Kutta de ordem 2','Runge-Kutta de ordem 4'};                       
                          tabela = [t.',uExata.',uEuler.',uEulerMelh.',uRK2.',uRK4.',erroEuler.',erroEulerMelh.',erroRK2.',erroRK4.'];
                          nomeColunas = {'t','Exata','Euler','Euler Melhorado','RK2','RK4','Erro Euler','Erro Euler Melhorado','Erro RK2','Erro RK4'};
                      else
                          legenda = {'Euler','Euler Melhorado','Runge-Kutta de ordem 2','Runge-Kutta de ordem 4'};                       
                          tabela = [t.',uEuler.',uEulerMelh.',uRK2.',uRK4.'];
                          nomeColunas = {'t','Euler','Euler Melhorado','RK2','RK4'}; 
                      end
        end
                T = array2table(tabela);
                app.UITable.Data = T;
                app.UITable.ColumnName = nomeColunas;
                hold(app.UIAxes,"off")
                legend(app.UIAxes,legenda)
        
catch ME
                errordlg(ME.message,"Erro","modal")
end                                                             
        end

        % Value changed function: ddProblemas
        function ddProblemasValueChanged(app, event)
            value = app.ddProblemas.Value;
            switch value
                case "1"
                    strG = "-sin(u)-0.3*v";
                    a = 0; b = 15; n = 100;
                    u0 = pi/2; v0 = 0;
                case "2"
                    strG = "-16*u";
                    a = 0; b = 4; n = 100;
                    u0 = 9; v0 = 0;
                case "3"
                    strG = "-10*v-25*u";
                    a = 0; b = 2; n = 100;
                    u0 = 0; v0 = -4;
                case "4"
                    strG = "-5*v-4*u+2*exp(-t/4)";
                    a = 0; b = 10; n = 100;
                    u0 = 0; v0 = 0;
                case"5"
                    strG = "-6*v-25*u";
                    a = 0; b = 10; n = 100;
                    u0 = 4; v0 = -20;
            end
            app.eG.Value = strG;
            app.eA.Value = a; app.eB.Value = b; app.eN.Value = n;
            app.eU0.Value = u0; app.eV0.Value = v0;
            bAtualizarButtonPushed(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrabalhoFigure = uifigure('Visible', 'off');
            app.TrabalhoFigure.Name = 'PVI_SED';
            app.TrabalhoFigure.Icon = 'LogoIsec.png';
            app.TrabalhoFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TrabalhoFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosPVI = uipanel(app.LeftPanel);
            app.DadosPVI.Title = 'Dados do PVI';
            app.aLabel = uilabel(app.DadosPVI);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.DadosPVI, 'numeric');
            app.eB = uieditfield(app.DadosPVI, 'numeric');
            app.eB.Value = 15;
            app.bLabel = uilabel(app.DadosPVI);
            app.bLabel.Text = 'b';
            app.eN = uieditfield(app.DadosPVI, 'numeric');
            app.eN.RoundFractionalValues = 'on';
            app.eN.Value = 100;
            app.nLabel = uilabel(app.DadosPVI);
            app.nLabel.Text = 'n';
            app.u0Label = uilabel(app.DadosPVI);
            app.u0Label.Text = 'u0';
            app.eU0 = uieditfield(app.DadosPVI, 'numeric');
            app.eU0.Value = 1.5707963267949;
            app.ftuvLabel = uilabel(app.DadosPVI);
            app.ftuvLabel.Text = 'f(t,u,v) = ';
            app.eF = uieditfield(app.DadosPVI, 'text');
            app.eF.Value = 'v';
            app.gtuvLabel = uilabel(app.DadosPVI);
            app.gtuvLabel.Text = 'g(t,u,v) = ';
            app.eG = uieditfield(app.DadosPVI, 'text');
            app.eG.Value = '-sin(u)-(0.3)*v';
            app.v0Label = uilabel(app.DadosPVI);
            app.v0Label.Text = 'v0';
            app.eV0 = uieditfield(app.DadosPVI, 'numeric');
            app.SdeM = uibuttongroup(app.LeftPanel);
            app.SdeM.Title = 'Seleção de Métodos';
            app.MEulerbtn = uiradiobutton(app.SdeM);
            app.MEulerbtn.Text = ' Método Euler';
            app.MEulerbtn.Value = true;
            app.MEulerMbtn = uiradiobutton(app.SdeM);
            app.MEulerMbtn.Text = ' Método Euler Melhorado';
            app.RungeKuttadeordem2btn = uiradiobutton(app.SdeM);
            app.RungeKuttadeordem2btn.Text = ' Runge-Kutta de ordem 2';
            app.RungeKuttadeordem4btn = uiradiobutton(app.SdeM);
            app.RungeKuttadeordem4btn.Text = ' Runge-Kutta de ordem 4';
            app.Todosbtn = uiradiobutton(app.SdeM);
            app.Todosbtn.Text = ' Todos os Métodos';
            app.SairButton = uibutton(app.LeftPanel, 'push');
            app.SairButton.ButtonPushedFcn = createCallbackFcn(app, @SairButtonPushed, true);
            app.SairButton.Text = 'Sair';
            app.LimparButton = uibutton(app.LeftPanel, 'push');
            app.LimparButton.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.LimparButton.Text = 'Limpar';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.ProblemasdinmicosDropDownLabel = uilabel(app.LeftPanel);
            app.ProblemasdinmicosDropDownLabel.Text = 'Problemas dinâmicos';
            app.ddProblemas = uidropdown(app.LeftPanel);
            app.ddProblemas.Items = {'Pêndulo', 'Mola-Massa sem amortecimento', 'Mola-Massa com amortecimento', 'Vibratório Mecânico', 'Circuitos Eletrónicos'};
            app.ddProblemas.ItemsData = {'1', '2', '3', '4', '5', ''};
            app.ddProblemas.ValueChangedFcn = createCallbackFcn(app, @ddProblemasValueChanged, true);
            app.ddProblemas.Value = '1';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Gráficos')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.InfoMenu = uimenu(app.TrabalhoFigure);
            app.InfoMenu.Text = 'Info';
            app.RealizadoporMenu = uimenu(app.InfoMenu);
            app.RealizadoporMenu.MenuSelectedFcn = createCallbackFcn(app, @RealizadoporMenuSelected, true);
            app.RealizadoporMenu.Text = 'Realizado por:';
            app.AutoresMenu = uimenu(app.InfoMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.RelatrioMenu = uimenu(app.InfoMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            % Show the figure after all components are created
            app.TrabalhoFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appPVI_SED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrabalhoFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrabalhoFigure)
        end
    end
end