classdef appMaquiaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MquinaClculoDiferencialeIntegralUIFigure  matlab.ui.Figure
        InfoMenu                        matlab.ui.container.Menu
        RealizadoporMenu                matlab.ui.container.Menu
        AutoresMenu                     matlab.ui.container.Menu
        RelatrioMenu                    matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        tgFrVr                          matlab.ui.container.Tab
        pDados                          matlab.ui.container.Panel
        ClculoDiferencialeIntegralPanel  matlab.ui.container.Panel
        fxLabel                         matlab.ui.control.Label
        eF                              matlab.ui.control.EditField
        aLabel                          matlab.ui.control.Label
        eA                              matlab.ui.control.NumericEditField
        eH                              matlab.ui.control.NumericEditField
        hLabel                          matlab.ui.control.Label
        eB                              matlab.ui.control.NumericEditField
        bLabel                          matlab.ui.control.Label
        ResultadoIntegralLabel          matlab.ui.control.Label
        eIntN                           matlab.ui.control.NumericEditField
        DerivaesNumricasButtonGroup     matlab.ui.container.ButtonGroup
        DP2Button                       matlab.ui.control.RadioButton
        DR2Button                       matlab.ui.control.RadioButton
        DR3Button                       matlab.ui.control.RadioButton
        DP3Button                       matlab.ui.control.RadioButton
        DC3Button                       matlab.ui.control.RadioButton
        D2OButton                       matlab.ui.control.RadioButton
        bAtualizar                      matlab.ui.control.Button
        LimparButton                    matlab.ui.control.Button
        SairButton                      matlab.ui.control.Button
        AproximaoButtonGroup            matlab.ui.container.ButtonGroup
        MtododeTrapzioButton            matlab.ui.control.RadioButton
        MtododeSimpsonButton            matlab.ui.control.RadioButton
        EscolhaLabel                    matlab.ui.control.Label
        ddEscolha                       matlab.ui.control.DropDown
        UIMostrador                     matlab.ui.control.UIAxes
        pResultados                     matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        tgFr2Vr                         matlab.ui.container.Tab
        pDados_2                        matlab.ui.container.Panel
        ClculoDiferencialeIntegralPanel_2  matlab.ui.container.Panel
        fxyLabel                        matlab.ui.control.Label
        eF_2                            matlab.ui.control.EditField
        eA_2                            matlab.ui.control.NumericEditField
        eH_2                            matlab.ui.control.NumericEditField
        eB_2                            matlab.ui.control.NumericEditField
        eA_3                            matlab.ui.control.NumericEditField
        eH_3                            matlab.ui.control.NumericEditField
        eB_3                            matlab.ui.control.NumericEditField
        aLabel_2                        matlab.ui.control.Label
        hLabel_2                        matlab.ui.control.Label
        bLabel_2                        matlab.ui.control.Label
        aLabel_3                        matlab.ui.control.Label
        hLabel_3                        matlab.ui.control.Label
        bLabel_3                        matlab.ui.control.Label
        DerivaesNumricasButtonGroup_2   matlab.ui.container.ButtonGroup
        DerivadaParcialemrelaoaXButton  matlab.ui.control.RadioButton
        DerivadaParcialemrelaoaYButton  matlab.ui.control.RadioButton
        eHarmonica                      matlab.ui.control.EditField
        SairButton_2                    matlab.ui.control.Button
        LimparButton_2                  matlab.ui.control.Button
        bAtualizar_2                    matlab.ui.control.Button
        TipodeGrficoButtonGroup         matlab.ui.container.ButtonGroup
        SurfButton                      matlab.ui.control.RadioButton
        MeshButton                      matlab.ui.control.RadioButton
        UIMostrador_2                   matlab.ui.control.UIAxes
        pResultados_2                   matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ddEscolhaValueChanged(app, []) 
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            diferencialtipo = app.DerivaesNumricasButtonGroup.SelectedObject;
            integraltipo = app.AproximaoButtonGroup.SelectedObject;
            valueproblema = app.ddEscolha.Value;
            syms y(x)
            strF = app.eF.Value;
            f = @(x) eval(vectorize(strF));
            a = app.eA.Value; h = app.eH.Value; b = app.eB.Value;           
            dydxSym = diff(f(x));
            dydxSym2 = diff(f(x),2);
            
            [x,y,dydxDFP2] = NDerivadaFP2(f,a,b,h,y);
            [~,~,dydxDFR2] = NDerivadaFR2(f,a,b,h,y);
            [~,~,dydxDFR3] = NDerivadaFR3(f,a,b,h,y);
            [~,~,dydxDFP3] = NDerivadaFP3(f,a,b,h,y);
            [~,~,dydxDFC3] = NDerivadaFC3(f,a,b,h,y);
            [~,~,dydxD2O] = NDerivada2O(f,a,b,h,y);
            sExata = int(f(sym('x')));
            n = (b-a)/h;
            
            TestF = MException("MATLAB:SdeM:badF", ...
                "Introduza uma função em x");
            
            TestAMaior=MException("MATLAB:SdeM:badMaior", ...
                "O valor do b está incorreto");
            
            TestH = MException("MATLAB:MyAtualizar:badH", ...
                "O valor do h está incorreto");
         
            try               
                try
                    y = f(x);
                    set(app.eF,'BackgroundColor','g');
                    
                catch
                    set(app.eF,'BackgroundColor','r');
                    throw(TestF)
                    
                end
                
                if ~(isscalar(b) && isreal(b) && b > a)
                    set(app.eB,'BackgroundColor','r');
                    throw(TestAMaior)
                else
                    set(app.eB,'BackgroundColor','g');
                end
                
                if ~(h > 0)
                    set(app.eH,'BackgroundColor','r');
                    throw(TestH)
                else
                    set(app.eH,'BackgroundColor','g');
                end
                
                if(valueproblema == '1')
                    switch diferencialtipo
                        
                        case app.DP2Button
                            
                            g =@(x) eval(vectorize(char(dydxSym)));
                            
                            dydxExata = g(x);
                            erroDFP2 = abs(dydxExata -dydxDFP2);
                            
                            plot(app.UIAxes,x,y,"-r+","LineWidth",1)
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x, dydxExata, "-bo","LineWidth",1)
                            plot(app.UIAxes,x,dydxDFP2,"-g*","LineWidth",1)
                            %fplot(app.UIAxes,@(x) eval(vectorize(char(strF))))
                            hold(app.UIAxes,"on")
                            
                            legenda = {'y','dydxExata','dydxDFP2'};
                            tabela = [x.',y.',dydxExata.', dydxDFP2.',erroDFP2.'];
                            nomeColunas = {'x','y','dydxExata','dydxDFP2','erroDFP2'};
                            
                        case app.DR2Button
                            
                            g =@(x) eval(vectorize(char(dydxSym)));
                            
                            dydxExata = g(x);
                            erroDFR2 = abs(dydxExata -dydxDFR2);
                            
                            plot(app.UIAxes,x,y,"-r+","LineWidth",1)
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x, dydxExata, "-bo","LineWidth",1)
                            plot(app.UIAxes,x,dydxDFR2,"-g*","LineWidth",1)
                            hold(app.UIAxes,"on")
                            
                            legenda = {'y','dydxExata','dydxDFR2'};
                            tabela = [x.',y.',dydxExata.', dydxDFR2.',erroDFR2.'];
                            nomeColunas = {'x','y','dydxExata','dydxDFR2','erroDFR2'};
                            
                        case app.DP3Button
                            
                            g =@(x) eval(vectorize(char(dydxSym)));
                            
                            dydxExata = g(x);
                            erroDFP3 = abs(dydxExata -dydxDFP3);
                            
                            plot(app.UIAxes,x,y,"-r+","LineWidth",1)
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x, dydxExata, "-bo","LineWidth",1)
                            plot(app.UIAxes,x,dydxDFP3,"-g*","LineWidth",1)
                            hold(app.UIAxes,"on")
                            
                            legenda = {'y','dydxExata','dydxDFP3'};
                            tabela = [x.',y.',dydxExata.', dydxDFP3.',erroDFP3.'];
                            nomeColunas = {'x','y','dydxExata','dydxDFP3','erroDFP'};
                            
                        case app.DR3Button
                            
                            g =@(x) eval(vectorize(char(dydxSym)));
                            
                            dydxExata = g(x);
                            erroDFR3 = abs(dydxExata -dydxDFR3);
                            
                            plot(app.UIAxes,x,y,"-r+","LineWidth",1)
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x, dydxExata, "-bo","LineWidth",1)
                            plot(app.UIAxes,x,dydxDFR3,"-g*","LineWidth",1)
                            hold(app.UIAxes,"on")
                            
                            legenda = {'y','dydxExata','dydxDFR3'};
                            tabela = [x.',y.',dydxExata.', dydxDFR3.',erroDFR3.'];
                            nomeColunas = {'x','y','dydxExata','dydxDFR3','erroDFR3'};
                            
                        case app.DC3Button
                            
                            g =@(x) eval(vectorize(char(dydxSym)));
                            
                            dydxExata = g(x);
                            erroDFC3 = abs(dydxExata -dydxDFC3);
                            
                            plot(app.UIAxes,x,y,"-r+","LineWidth",1)
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x, dydxExata, "-bo","LineWidth",1)
                            plot(app.UIAxes,x,dydxDFC3,"-g*","LineWidth",1)
                            hold(app.UIAxes,"on")
                            legenda = {'y','dydxExata','dydxDFC3'};
                            tabela = [x.',y.',dydxExata.', dydxDFC3.',erroDFC3.'];
                            nomeColunas = {'x','y','dydxExata','dydxDFC3','erroDFC3'};
                            
                        case app.D2OButton
                            g =@(x) eval(vectorize(char(dydxSym2)));
                            
                            dydxExata = g(x);
                            erroD2O = abs(dydxExata -dydxD2O);
                            
                            plot(app.UIAxes,x,y,"-r+","LineWidth",1)
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x, dydxExata, "-bo","LineWidth",1)
                            plot(app.UIAxes,x,dydxD2O,"-g*","LineWidth",1)
                            hold(app.UIAxes,"on")
                            legenda = {'y','dydxExata','dydxDeriva2Ordem'};
                            tabela = [x.',y.',dydxExata.', dydxD2O.',erroD2O.'];
                            nomeColunas = {'x','y','dydxExata','dydxD2O','erroD2O'};
                    end
                    hold(app.UIAxes,"off")
                    Info = array2table(tabela);
                    app.UITable.Data = Info;
                    app.UITable.ColumnName = nomeColunas;
                    
                    if~(diferencialtipo == app.D2OButton)
                        formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                        cla(app.UIMostrador)
                        text(app.UIMostrador,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                    else
                        formaLatex =['$','\frac{d^2y}{dx^2}=',latex(dydxSym2),'$'];
                        cla(app.UIMostrador)
                        text(app.UIMostrador,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                    end
                    
                else
                    hold(app.UIAxes,"off")
                    formaLatex =['$','\int_{a}^{b}',strF,'\,dx =',latex(sExata),'\Big|_a^b','+c','$'];
                    cla(app.UIMostrador)
                    text(app.UIMostrador,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                    area(app.UIAxes,x,y,"FaceColor","r","FaceAlpha",0.5)
                    legenda = {'Área'};
                end
                legend(app.UIAxes,legenda)
                
                switch integraltipo
                    case app.MtododeSimpsonButton
                        S = RSimpson(f,a,b,n);
                        app.eIntN.Value = S;
                    case app.MtododeTrapzioButton
                        T = RTrapezios(f,a,b,n);
                        app.eIntN.Value = T;
                end
             
            catch ME
                errordlg(ME.message,"ERRO","modal")
            end
        end

        % Button pushed function: bAtualizar_2
        function bAtualizar_2ButtonPushed(app, event)
            tipodegrafico = app.TipodeGrficoButtonGroup.SelectedObject;
            tipodederivada = app.DerivaesNumricasButtonGroup_2 .SelectedObject;
            syms x y z(x,y)
            strFxy = app.eF_2.Value;
            f =@(x,y) eval(vectorize(strFxy));
            parcialx=diff(f(x,y),x);
            parcialy=diff(f(x,y),y);
            a = app.eA_2.Value; h1 = app.eH_2.Value; b = app.eB_2.Value;
            c = app.eA_3.Value; h2 = app.eH_3.Value; d = app.eB_3.Value;
            [x,y] = meshgrid(a:h1:b, c:h2:d);
            z = f(x,y);
            
            
            TestF1 = MException("MATLAB:SdeM:badF", ...
                "Introduza uma função em x ou y");
            
            TestAMaior1=MException("MATLAB:SdeM:badMaior", ...
                "O valor do b está incorreto");
            
            TestCMaior=MException("MATLAB:SdeM:badMaior", ...
                "O valor do d está incorreto");
            
            TestH1 = MException("MATLAB:MyAtualizar:badH", ...
                "O valor do h está incorreto");
            
            try
                try
                    z = f(x,y);
                    set(app.eF_2,'BackgroundColor','g');
                    
                catch
                    set(app.eF_2,'BackgroundColor','r');
                    throw(TestF1)
                    
                end
                
                if ~(isscalar(b) && isreal(b) && b > a)
                    set(app.eB_2,'BackgroundColor','r');
                    throw(TestAMaior1)
                else
                    set(app.eB_2,'BackgroundColor','g');
                end
                
                if ~(h1 > 0)
                    set(app.eH_2,'BackgroundColor','r');
                    throw(TestH1)
                else
                    set(app.eH_2,'BackgroundColor','g');
                end
                
                if ~(isscalar(d) && isreal(d) && d > c)
                    set(app.eB_3,'BackgroundColor','r');
                    throw(TestCMaior)
                else
                    set(app.eB_3,'BackgroundColor','g');
                end
                
                if ~(h2 > 0)
                    set(app.eH_3,'BackgroundColor','r');
                    throw(TestH1)
                else
                    set(app.eH_3,'BackgroundColor','g');
                end
                if isHarmonica(f)
                    strHarmonica = "f é harmónica";
                    corHarmonica = "g";
                else
                    strHarmonica = "f não é harmónica";
                    corHarmonica = "r";
                end
                app.eHarmonica.Value = strHarmonica;
                app.eHarmonica.FontColor = corHarmonica;
                
                switch tipodederivada
                    case app.DerivadaParcialemrelaoaXButton
                        formaLatex =['$','\frac{\partial f}{\partial x}=','\frac{\partial}{\partial x}',strFxy,'=',latex(parcialx),'$'];
                        cla(app.UIMostrador_2)
                        text(app.UIMostrador_2,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                    case app.DerivadaParcialemrelaoaYButton
                        formaLatex =['$','\frac{\partial f}{\partial y}=','\frac{\partial}{\partial y}',strFxy,'=',latex(parcialy),'$'];
                        cla(app.UIMostrador_2)
                        text(app.UIMostrador_2,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                end
                
                switch tipodegrafico
                    case app.MeshButton
                        mesh(app.UIAxes_2, x,y,z)
                    case app.SurfButton
                        surf(app.UIAxes_2, x,y,z)
                end
                
            catch ME
                errordlg(ME.message,"ERRO","modal")
            end
            
        end

        % Button pushed function: SairButton
        function SairButtonPushed(app, event)
            exitmessage = questdlg("Deseja mesmo sair?","Sair?","Sim","Não","Não");
            
            if strcmp(exitmessage,"Sim")
                close(app.MquinaClculoDiferencialeIntegralUIFigure)
            end
        end

        % Button pushed function: SairButton_2
        function SairButton_2Pushed(app, event)
            exitmessage = questdlg("Deseja mesmo sair?","Sair?","Sim","Não","Não");
            
            if strcmp(exitmessage,"Sim")
                close(app.MquinaClculoDiferencialeIntegralUIFigure)
            end
        end

        % Value changed function: ddEscolha
        function ddEscolhaValueChanged(app, event)
            escolha = app.ddEscolha.Value;
            %bAtualizarButtonPushed(app, event)
            switch escolha
                case "1"
                    app.AproximaoButtonGroup.Enable = 'off';
                    app.DerivaesNumricasButtonGroup.Enable = 'on';
                    app.eIntN.Enable = 'off';
                case "2"
                    app.DerivaesNumricasButtonGroup.Enable = 'off';
                    app.AproximaoButtonGroup.Enable = 'on';
                    app.eIntN.Enable = 'on';
            end                       
        end

        % Button pushed function: LimparButton
        function LimparButtonPushed(app, event)
            cla(app.UIAxes);                      % limpa o gráfico
            cla(app.UIMostrador)                  %
            app.UITable.Data = {};                %
            set(app.UITable,'columnname',{});     % limpa a tabela
            app.eA.Value = 0;                     %
            app.eB.Value = 2*pi;                  %
            app.eH.Value = 0.1;                   %
            app.eIntN.Value = 0;                  % limpa as variáveis
        end

        % Button pushed function: LimparButton_2
        function LimparButton_2Pushed(app, event)
            cla(app.UIAxes_2);                      % limpa o gráfico
            cla(app.UIMostrador_2)                  %            
            app.eHarmonica.Value = '';              %
            app.eA_2.Value = 0;                     %
            app.eB_2.Value = 2;                     %
            app.eA_3.Value = 0;                     %
            app.eB_3.Value = 2;                     %
            app.eH_2.Value = 0.1;                   %
            app.eH_3.Value = 0.1;                   %limpa as variáveis
        end

        % Menu selected function: RealizadoporMenu
        function RealizadoporMenuSelected(app, event)
             msgbox(["Paulo Gouveia a2020121705@isec.pt";"Miguel Ferreira a2020107016@isec.pt";"Pablo Amaral a2020143935@isec.pt"],"Autores:")
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            run("Autores.mlapp") %abrir outra aplicação através do menu
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
             open AM2_Relatório_Atividade3.pdf
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MquinaClculoDiferencialeIntegralUIFigure = uifigure('Visible', 'off');
            app.MquinaClculoDiferencialeIntegralUIFigure.Name = 'Máquina Cálculo Diferencial e Integral';
            app.MquinaClculoDiferencialeIntegralUIFigure.Icon = 'LogoIsec.png';
            app.InfoMenu = uimenu(app.MquinaClculoDiferencialeIntegralUIFigure);
            app.InfoMenu.Text = 'Info';
            app.RealizadoporMenu = uimenu(app.InfoMenu);
            app.RealizadoporMenu.MenuSelectedFcn = createCallbackFcn(app, @RealizadoporMenuSelected, true);
            app.RealizadoporMenu.Text = 'Realizado por:';
            app.AutoresMenu = uimenu(app.InfoMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.RelatrioMenu = uimenu(app.InfoMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.TabGroup = uitabgroup(app.MquinaClculoDiferencialeIntegralUIFigure);
            app.tgFrVr = uitab(app.TabGroup);
            app.tgFrVr.Title = 'Função real de variável real y = f(x)';
            app.pDados = uipanel(app.tgFrVr);
            app.ClculoDiferencialeIntegralPanel = uipanel(app.pDados);
            app.ClculoDiferencialeIntegralPanel.Title = 'Cálculo Diferencial e Integral';
            app.fxLabel = uilabel(app.ClculoDiferencialeIntegralPanel);
            app.fxLabel.Text = 'f(x)=';
            app.eF = uieditfield(app.ClculoDiferencialeIntegralPanel, 'text');
            app.eF.Value = 'sin(x)';
            app.aLabel = uilabel(app.ClculoDiferencialeIntegralPanel);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.ClculoDiferencialeIntegralPanel, 'numeric');
            app.eH = uieditfield(app.ClculoDiferencialeIntegralPanel, 'numeric');
            app.eH.Value = 0.1;
            app.hLabel = uilabel(app.ClculoDiferencialeIntegralPanel);
            app.hLabel.Text = 'h';
            app.eB = uieditfield(app.ClculoDiferencialeIntegralPanel, 'numeric');
            app.eB.Value = 6.28318530717959;
            app.bLabel = uilabel(app.ClculoDiferencialeIntegralPanel);
            app.bLabel.Text = 'b';
            app.ResultadoIntegralLabel = uilabel(app.pDados);
            app.ResultadoIntegralLabel.Text = {'Resultado '; 'Integral:'};
            app.eIntN = uieditfield(app.pDados, 'numeric');
            app.eIntN.Editable = 'off';
            app.DerivaesNumricasButtonGroup = uibuttongroup(app.pDados);
            app.DerivaesNumricasButtonGroup.Title = 'Derivações Numéricas';
            app.DP2Button = uiradiobutton(app.DerivaesNumricasButtonGroup);
            app.DP2Button.Text = 'Diferenças Progressivas em 2 Pontos';
            app.DP2Button.Value = true;
            app.DR2Button = uiradiobutton(app.DerivaesNumricasButtonGroup);
            app.DR2Button.Text = 'Diferenças Regressivas em 2 Pontos';
            app.DR3Button = uiradiobutton(app.DerivaesNumricasButtonGroup);
            app.DR3Button.Text = 'Diferenças Regressivas em 3 Pontos';
            app.DP3Button = uiradiobutton(app.DerivaesNumricasButtonGroup);
            app.DP3Button.Text = 'Diferenças Progressivas em 3 Pontos';
            app.DC3Button = uiradiobutton(app.DerivaesNumricasButtonGroup);
            app.DC3Button.Text = 'Diferenças Centradas em 3 Pontos';
            app.D2OButton = uiradiobutton(app.DerivaesNumricasButtonGroup);
            app.D2OButton.Text = 'Derivada em segunda ordem';
            app.bAtualizar = uibutton(app.pDados, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.LimparButton = uibutton(app.pDados, 'push');
            app.LimparButton.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.LimparButton.Text = 'Limpar';
            app.SairButton = uibutton(app.pDados, 'push');
            app.SairButton.ButtonPushedFcn = createCallbackFcn(app, @SairButtonPushed, true);
            app.SairButton.Text = 'Sair';
            app.AproximaoButtonGroup = uibuttongroup(app.pDados);
            app.AproximaoButtonGroup.Title = 'Aproximação';
            app.MtododeTrapzioButton = uiradiobutton(app.AproximaoButtonGroup);
            app.MtododeTrapzioButton.Text = 'Método de Trapézio';
            app.MtododeTrapzioButton.Value = true;
            app.MtododeSimpsonButton = uiradiobutton(app.AproximaoButtonGroup);
            app.MtododeSimpsonButton.Text = 'Método de Simpson';
            app.EscolhaLabel = uilabel(app.pDados);
            app.EscolhaLabel.Text = 'Escolha';
            app.ddEscolha = uidropdown(app.pDados);
            app.ddEscolha.Items = {'Derivada', 'Integral'};
            app.ddEscolha.ItemsData = {'1', '2', ''};
            app.ddEscolha.ValueChangedFcn = createCallbackFcn(app, @ddEscolhaValueChanged, true);
            app.ddEscolha.Value = '1';
            app.UIMostrador = uiaxes(app.pDados);
            app.UIMostrador.XTick = [];
            app.UIMostrador.YTick = [];
            app.pResultados = uipanel(app.tgFrVr);
            app.UITable = uitable(app.pResultados);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.pResultados);
            title(app.UIAxes, 'Gráfico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.tgFr2Vr = uitab(app.TabGroup);
            app.tgFr2Vr.Title = 'Função real de 2 variáveis reais z = f(x,y)';
            app.pDados_2 = uipanel(app.tgFr2Vr);
            app.ClculoDiferencialeIntegralPanel_2 = uipanel(app.pDados_2);
            app.ClculoDiferencialeIntegralPanel_2.Title = 'Cálculo Diferencial e Integral';
            app.fxyLabel = uilabel(app.ClculoDiferencialeIntegralPanel_2);
            app.fxyLabel.Text = 'f(x,y) = ';
            app.eF_2 = uieditfield(app.ClculoDiferencialeIntegralPanel_2, 'text');
            app.eF_2.Value = 'x^2+y^2';
            app.eA_2 = uieditfield(app.ClculoDiferencialeIntegralPanel_2, 'numeric');
            app.eA_2.Value = -2;
            app.eH_2 = uieditfield(app.ClculoDiferencialeIntegralPanel_2, 'numeric');
            app.eH_2.Value = 0.1;
            app.eB_2 = uieditfield(app.ClculoDiferencialeIntegralPanel_2, 'numeric');
            app.eB_2.Value = 2;
            app.eA_3 = uieditfield(app.ClculoDiferencialeIntegralPanel_2, 'numeric');
            app.eA_3.Value = -2;
            app.eH_3 = uieditfield(app.ClculoDiferencialeIntegralPanel_2, 'numeric');
            app.eH_3.Value = 0.1;
            app.eB_3 = uieditfield(app.ClculoDiferencialeIntegralPanel_2, 'numeric');
            app.eB_3.Value = 2;
            app.aLabel_2 = uilabel(app.ClculoDiferencialeIntegralPanel_2);
            app.aLabel_2.Text = 'a';
            app.hLabel_2 = uilabel(app.ClculoDiferencialeIntegralPanel_2);
            app.hLabel_2.Text = 'h';
            app.bLabel_2 = uilabel(app.ClculoDiferencialeIntegralPanel_2);
            app.bLabel_2.Text = 'b';
            app.aLabel_3 = uilabel(app.ClculoDiferencialeIntegralPanel_2);
            app.aLabel_3.Text = 'a';
            app.hLabel_3 = uilabel(app.ClculoDiferencialeIntegralPanel_2);
            app.hLabel_3.Text = 'h';
            app.bLabel_3 = uilabel(app.ClculoDiferencialeIntegralPanel_2);
            app.bLabel_3.Text = 'b';
            app.DerivaesNumricasButtonGroup_2 = uibuttongroup(app.pDados_2);
            app.DerivaesNumricasButtonGroup_2.Title = 'Derivações Numéricas';
            app.DerivadaParcialemrelaoaXButton = uiradiobutton(app.DerivaesNumricasButtonGroup_2);
            app.DerivadaParcialemrelaoaXButton.Text = 'Derivada Parcial em relação a X';
            app.DerivadaParcialemrelaoaXButton.Value = true;
            app.DerivadaParcialemrelaoaYButton = uiradiobutton(app.DerivaesNumricasButtonGroup_2);
            app.DerivadaParcialemrelaoaYButton.Text = 'Derivada Parcial em relação a Y';
            app.eHarmonica = uieditfield(app.pDados_2, 'text');
            app.SairButton_2 = uibutton(app.pDados_2, 'push');
            app.SairButton_2.ButtonPushedFcn = createCallbackFcn(app, @SairButton_2Pushed, true);
            app.SairButton_2.Text = 'Sair';
            app.LimparButton_2 = uibutton(app.pDados_2, 'push');
            app.LimparButton_2.ButtonPushedFcn = createCallbackFcn(app, @LimparButton_2Pushed, true);
            app.LimparButton_2.Text = 'Limpar';
            app.bAtualizar_2 = uibutton(app.pDados_2, 'push');
            app.bAtualizar_2.ButtonPushedFcn = createCallbackFcn(app, @bAtualizar_2ButtonPushed, true);
            app.bAtualizar_2.Text = 'Atualizar';
            app.TipodeGrficoButtonGroup = uibuttongroup(app.pDados_2);
            app.TipodeGrficoButtonGroup.Title = 'Tipo de Gráfico';
            app.SurfButton = uiradiobutton(app.TipodeGrficoButtonGroup);
            app.SurfButton.Text = 'Surf';
            app.SurfButton.Value = true;
            app.MeshButton = uiradiobutton(app.TipodeGrficoButtonGroup);
            app.MeshButton.Text = 'Mesh';
            app.UIMostrador_2 = uiaxes(app.pDados_2);
            app.UIMostrador_2.XTick = [];
            app.UIMostrador_2.YTick = [];
            app.pResultados_2 = uipanel(app.tgFr2Vr);
            app.UIAxes_2 = uiaxes(app.pResultados_2);
            title(app.UIAxes_2, 'Gráfico')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.MquinaClculoDiferencialeIntegralUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquiaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MquinaClculoDiferencialeIntegralUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MquinaClculoDiferencialeIntegralUIFigure)
        end
    end
end